classdef RF_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnvironmentCanvasPanel        matlab.ui.container.Panel
        Canvas2                       matlab.ui.control.UIAxes
        PlatformsPanel                matlab.ui.container.Panel
        TotalCarsEditField            matlab.ui.control.NumericEditField
        TotalCarsEditFieldLabel       matlab.ui.control.Label
        TotalPlatformsEditField       matlab.ui.control.NumericEditField
        TotalPlatformsEditFieldLabel  matlab.ui.control.Label
        AddPlatformsDropDown          matlab.ui.control.DropDown
        AddPlatformsDropDownLabel     matlab.ui.control.Label
        AddButton                     matlab.ui.control.Button
        EnvironmentPropertiesPanel    matlab.ui.container.Panel
        PlatformsDropDown             matlab.ui.control.DropDown
        PlatformsDropDownLabel        matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.Button
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        ScenerioViewPanel             matlab.ui.container.Panel
        Canvas                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        totalCars = 0 % Description
        totalPlatforms = 0 % Description
        platforms
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            value = str2num(app.AddPlatformsDropDown.Value);
            if app.totalPlatforms == 0
                myFieldNames = {'ID', 'x', 'y', 'z', 'length', 'width', 'height'};
                T = array2table(zeros(0,7),'VariableNames',myFieldNames);

                % create a table with the data and field names
                app.platforms = T;
            end

            if value == 1
                app.totalCars = app.totalCars + 1;
                app.totalPlatforms = app.totalPlatforms + 1;
                % Add car
                % Read the X, Y, and Z coordinates from the edit fields
                x = 1;
                y = 1;
                z = 1;

                % Define the vertices of the cube
                length = 1;
                width = 1;
                height = 1;
                vertices = [x-length/2 y-width/2 z-height/2;
                    x+length/2 y-width/2 z-height/2;
                    x+length/2 y+width/2 z-height/2;
                    x-length/2 y+width/2 z-height/2;
                    x-length/2 y-width/2 z+height/2;
                    x+length/2 y-width/2 z+height/2;
                    x+length/2 y+width/2 z+height/2;
                    x-length/2 y+width/2 z+height/2];

                % Define the faces of the cube
                faces = [1 2 3 4;
                    2 6 7 3;
                    4 3 7 8;
                    1 5 8 4;
                    1 2 6 5;
                    5 6 7 8];

                % Plot the cube on the canvas
                cube = patch('Parent', app.Canvas, 'Faces', faces, 'Vertices', vertices, 'FaceColor', 'blue', 'FaceAlpha', 0.5);
                car_str = 'Car_';
                car_count = num2str(app.totalCars);
                car_id = string(strcat(car_str,car_count));
                % add a new row to the table
                new_row = {car_id,x,y,z,length,width,height};
                T = [app.platforms; new_row];
                app.platforms = T;
            end

            x_all_platforms = app.platforms.x;
            y_all_platforms = app.platforms.y;
            z_all_platforms = app.platforms.z;
            plot(app.Canvas2, x_all_platforms, y_all_platforms, '*')

            app.TotalCarsEditField.Value = app.totalCars;
            app.TotalPlatformsEditField.Value = app.totalPlatforms;

        end

        % Drop down opening function: PlatformsDropDown
        function PlatformsDropDownOpening(app, event)
            app.PlatformsDropDown.Items = app.platforms{:, 'ID'};
            app.PlatformsDropDown.ItemsData = app.platforms{:, 'ID'};
            %             app.PlatformsDropDown.Value = app.platforms{1, 'ID'};
        end

        % Value changed function: PlatformsDropDown
        function PlatformsDropDownValueChanged(app, event)
            value = app.PlatformsDropDown.Value;
            disp(app.platforms)
            row = find(strcmp(app.platforms.ID, (value)));
            disp(row)
            rowTable = app.platforms(row, :);
            app.XEditField.Value = rowTable.x;
            app.YEditField.Value = rowTable.y;
            app.ZEditField.Value = rowTable.z;
            app.LengthEditField.Value = rowTable.length;
            app.WidthEditField.Value = rowTable.width;
            app.HeightEditField.Value = rowTable.height;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Read the X, Y, and Z coordinates from the edit fields
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;

            % Define the vertices of the cube
            length = app.LengthEditField.Value;
            width = app.WidthEditField.Value;
            height = app.HeightEditField.Value;

            row = find(strcmp(app.platforms.ID, app.PlatformsDropDown.Value));
            rowTable = app.platforms(row, :);
            rowTable.x = app.XEditField.Value ;
            rowTable.y = app.YEditField.Value;
            rowTable.z = app.ZEditField.Value;
            rowTable.length = app.LengthEditField.Value  ;
            rowTable.width = app.WidthEditField.Value;
            rowTable.height = app.HeightEditField.Value;
            app.platforms(row, :) = rowTable;

            vertices = [x-length/2 y-width/2 z-height/2;
                x+length/2 y-width/2 z-height/2;
                x+length/2 y+width/2 z-height/2;
                x-length/2 y+width/2 z-height/2;
                x-length/2 y-width/2 z+height/2;
                x+length/2 y-width/2 z+height/2;
                x+length/2 y+width/2 z+height/2;
                x-length/2 y+width/2 z+height/2];

            % Define the faces of the cube
            faces = [1 2 3 4;
                2 6 7 3;
                4 3 7 8;
                1 5 8 4;
                1 2 6 5;
                5 6 7 8];

            % Plot the cube on the canvas
            cube = patch('Parent', app.Canvas, 'Faces', faces, 'Vertices', vertices, 'FaceColor', 'Blue', 'FaceAlpha', 0.5);
            x_all_platforms = app.platforms.x;
            y_all_platforms = app.platforms.y;
            z_all_platforms = app.platforms.z;
            plot(app.Canvas2, x_all_platforms, y_all_platforms, '*')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScenerioViewPanel = uipanel(app.UIFigure);
            app.ScenerioViewPanel.Title = 'Scenerio View';
            app.Canvas = uiaxes(app.ScenerioViewPanel);
            app.Canvas.XLim = [-20 20];
            app.Canvas.YLim = [-20 20];
            app.Canvas.ZLim = [-20 20];
            app.Canvas.XTickLabel = '';
            app.Canvas.YTickLabel = '';
            app.Canvas.ZTickLabel = '';
            colormap(app.Canvas, 'copper')
            app.EnvironmentPropertiesPanel = uipanel(app.UIFigure);
            app.EnvironmentPropertiesPanel.Title = 'Environment Properties';
            app.XEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.UpdateButton = uibutton(app.EnvironmentPropertiesPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PlatformsDropDownLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.PlatformsDropDownLabel.Text = 'Platforms';
            app.PlatformsDropDown = uidropdown(app.EnvironmentPropertiesPanel);
            app.PlatformsDropDown.Items = {};
            app.PlatformsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PlatformsDropDownOpening, true);
            app.PlatformsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlatformsDropDownValueChanged, true);
            app.PlatformsDropDown.Value = {};
            app.PlatformsPanel = uipanel(app.UIFigure);
            app.PlatformsPanel.Title = 'Platforms';
            app.AddButton = uibutton(app.PlatformsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddPlatformsDropDownLabel = uilabel(app.PlatformsPanel);
            app.AddPlatformsDropDownLabel.Text = 'Add Platforms';
            app.AddPlatformsDropDown = uidropdown(app.PlatformsPanel);
            app.AddPlatformsDropDown.Items = {'1. Car', '2.', '3.'};
            app.AddPlatformsDropDown.ItemsData = {'1', '2', '3'};
            app.AddPlatformsDropDown.Value = '1';
            app.TotalPlatformsEditFieldLabel = uilabel(app.PlatformsPanel);
            app.TotalPlatformsEditFieldLabel.Text = 'Total Platforms';
            app.TotalPlatformsEditField = uieditfield(app.PlatformsPanel, 'numeric');
            app.TotalCarsEditFieldLabel = uilabel(app.PlatformsPanel);
            app.TotalCarsEditFieldLabel.Text = 'Total Cars';
            app.TotalCarsEditField = uieditfield(app.PlatformsPanel, 'numeric');
            app.EnvironmentCanvasPanel = uipanel(app.UIFigure);
            app.EnvironmentCanvasPanel.Title = 'Environment Canvas';
            app.Canvas2 = uiaxes(app.EnvironmentCanvasPanel);
            xlabel(app.Canvas2, 'X')
            ylabel(app.Canvas2, 'Y')
            zlabel(app.Canvas2, 'Z')
            app.Canvas2.XLim = [-10 10];
            app.Canvas2.YLim = [-10 10];
            app.Canvas2.ZLim = [-10 10];
            app.Canvas2.XLimitMethod = 'padded';
            app.Canvas2.YLimitMethod = 'padded';
            app.Canvas2.ZLimitMethod = 'padded';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RF_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end