classdef VisualizeAntennaCoverage_test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup3                       matlab.ui.container.TabGroup
        SwarmPropertiesTab              matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        TransmitterSitesListBox         matlab.ui.control.ListBox
        TransmitterSitesListBoxLabel    matlab.ui.control.Label
        ReceiverSitesListBox            matlab.ui.control.ListBox
        ReceiverSitesListBoxLabel       matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        DirectionalAntennaTab           matlab.ui.container.Tab
        TiltAxisDropDown                matlab.ui.control.DropDown
        TiltAxisDropDownLabel           matlab.ui.control.Label
        PlotDirectivityPatternButton    matlab.ui.control.Button
        TiltAngleEditField              matlab.ui.control.NumericEditField
        TiltAngleEditFieldLabel         matlab.ui.control.Label
        PlotIdealcoverageAreaButton     matlab.ui.control.Button
        TabGroup_2                      matlab.ui.container.TabGroup
        FileUploadTab                   matlab.ui.container.Tab
        PathofFileUploadedEditField     matlab.ui.control.EditField
        PathofFileUploadedEditFieldLabel  matlab.ui.control.Label
        UploadFileButton                matlab.ui.control.Button
        TransmitterTab_2                matlab.ui.container.Tab
        TransmiterLatitudeEditField     matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        TransmitterLongitudeEditField   matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        TransmitterAddSiteButton        matlab.ui.control.Button
        TransmitterNameofSiteEditField  matlab.ui.control.EditField
        NameofSiteEditFieldLabel        matlab.ui.control.Label
        TransmitterPowerwattsEditField  matlab.ui.control.NumericEditField
        TransmitterPowerwattsEditFieldLabel  matlab.ui.control.Label
        AntennaHeightmetersEditField    matlab.ui.control.NumericEditField
        AntennaHeightmetersEditFieldLabel  matlab.ui.control.Label
        TransmitterFrequencye9HzEditField  matlab.ui.control.NumericEditField
        Frequencye9Label                matlab.ui.control.Label
        ReceiverTab_2                   matlab.ui.container.Tab
        ReceiverAddSiteButton           matlab.ui.control.Button
        ReceiverLongitudeEditField      matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        ReceiverLatitudeEditField       matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
        ReceiverNameofSiteEditField     matlab.ui.control.EditField
        NameofSiteEditField_2Label      matlab.ui.control.Label
        ReceiverSentivityEditField      matlab.ui.control.NumericEditField
        ReceiverSentivityEditFieldLabel  matlab.ui.control.Label
        SignalMapGenerationTab_2        matlab.ui.container.Tab
        HeatMapbasedonGridButton        matlab.ui.control.Button
        mEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        NumberofSampleEditField         matlab.ui.control.NumericEditField
        NumberofSampleEditFieldLabel    matlab.ui.control.Label
        SavingStatusEditField           matlab.ui.control.EditField
        SavingStatusEditFieldLabel      matlab.ui.control.Label
        SaveFilesButton                 matlab.ui.control.Button
        PathofDirectoryEditField        matlab.ui.control.EditField
        PathofDirectoryEditFieldLabel   matlab.ui.control.Label
        VisualizeSiteCoverageAreaButton  matlab.ui.control.Button
        GenerateRandomSitesButton       matlab.ui.control.Button
        RandomSitesDropDown             matlab.ui.control.DropDown
        RandomSitesDropDownLabel        matlab.ui.control.Label
        NumberofTransmitterinaRegionDropDown  matlab.ui.control.DropDown
        NumberofTransmitterinaRegionDropDownLabel  matlab.ui.control.Label
        DistributionDropDown_2          matlab.ui.control.DropDown
        DistributionDropDown_2Label     matlab.ui.control.Label
        SouthWestLongitudeEditField_3   matlab.ui.control.NumericEditField
        SouthWestLongitudeEditField_3Label  matlab.ui.control.Label
        NorthEastLongitudeEditField_3   matlab.ui.control.NumericEditField
        NorthEastLongitudeEditField_3Label  matlab.ui.control.Label
        SouthWestLatitudeEditField_3    matlab.ui.control.NumericEditField
        SouthWestLatitudeEditField_3Label  matlab.ui.control.Label
        NorthEastLatitudeEditField_3    matlab.ui.control.NumericEditField
        NorthEastLatitudeEditField_3Label  matlab.ui.control.Label
        MapPanel_2                      matlab.ui.container.Panel
        BaseMapDropDown                 matlab.ui.control.DropDown
        BaseMapDropDownLabel            matlab.ui.control.Label
        MapPanel                        matlab.ui.container.Panel
        DirectivityPatternPanel         matlab.ui.container.Panel
        DirectivityPlot                 matlab.ui.control.UIAxes
        EnvironmentalPropertiesPanel    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TargetRegionTab                 matlab.ui.container.Tab
        SouthWestLongitudeEditField     matlab.ui.control.NumericEditField
        SouthWestLongitudeEditField_2Label  matlab.ui.control.Label
        NorthEastLongitudeEditField     matlab.ui.control.NumericEditField
        NorthEastLongitudeEditFieldLabel  matlab.ui.control.Label
        PlotTargetRegionButton          matlab.ui.control.Button
        SouthWestLatitudeEditField      matlab.ui.control.NumericEditField
        SouthWestLongitudeLabel         matlab.ui.control.Label
        NorthEastLatitudeEditField      matlab.ui.control.NumericEditField
        NorthEastLatitudeEditFieldLabel  matlab.ui.control.Label
        PropagationModelTab             matlab.ui.container.Tab
        PropagationModelDropDown        matlab.ui.control.DropDown
        PropagationModelDropDownLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Latitudes = []% Description
        Longitudes = []% Description
        transmitterLat = []
        receiverLat = []
        transmitterLon = []
        receiverLon = []
        transmitters = []
        receivers = []
        siteType = []
        coordinateSytem = []
        transmitterAntenna = []
        transmitterAntennaAngle = []
        transmitterAntennaHeight = []
        transmitterAntennaPosition = []
        transmitterSystemLoss = []
        transmitterFrequency = []
        transmitterPower = []
        receiverAntenna = []
        receiverAntennaAngle = []
        receiverAntennaHeight = []
        receiverAntennaPosition = []
        receiverSystemLoss = []
        receiverSenstivity = []
        geoPlotAxes
        txa
        rxsa
        randLatitudes 
        randLongitudes
        centroidLat
        centroidLon
        siteN
        figs = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransmitterAddSiteButton
        function TransmitterAddSiteButtonPushed(app, event)
            app.transmitters = [app.transmitters app.TransmitterNameofSiteEditField.Value];
            app.transmitterLat = [app.transmitterLat (app.TransmiterLatitudeEditField.Value)];
            app.transmitterLon = [app.transmitterLon (app.TransmitterLongitudeEditField.Value)];
            app.transmitterAntenna = [app.transmitterAntenna "dipole"];
            app.transmitterAntennaAngle = [app.transmitterAntennaAngle 0];
            app.transmitterAntennaHeight = [app.transmitterAntennaHeight app.AntennaHeightmetersEditField.Value];
            app.transmitterAntennaPosition = [app.transmitterAntennaPosition 0];
            app.transmitterSystemLoss = [app.transmitterSystemLoss 0];
            app.transmitterFrequency = [app.transmitterFrequency app.TransmitterFrequencye9HzEditField.Value];
            app.transmitterPower = [app.transmitterPower app.TransmitterPowerwattsEditField.Value];

            app.TransmitterSitesListBox.Items{end+1} = app.TransmitterNameofSiteEditField.Value;
%             app.Latitudes = [app.Latitudes (app.TransmiterLatitudeEditField.Value)];
%             app.Longitudes = [app.Longitudes (app.TransmitterLongitudeEditField.Value)];
            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: ReceiverAddSiteButton
        function ReceiverAddSiteButtonPushed(app, event)
            app.receivers = [app.receivers app.ReceiverNameofSiteEditField.Value];
            app.receiverLat = [app.receiverLat app.ReceiverLatitudeEditField.Value];
            app.receiverLon = [app.receiverLon app.ReceiverLongitudeEditField.Value];
            app.receiverAntenna = [app.receiverAntenna "dipole"];
            app.receiverAntennaAngle = [app.receiverAntennaAngle 0];
            app.receiverAntennaHeight = [app.receiverAntennaHeight 10];
            app.receiverAntennaPosition = [app.receiverAntennaPosition 0];
            app.receiverSystemLoss = [app.receiverSystemLoss 0];
            app.receiverSenstivity = [app.receiverSenstivity app.ReceiverSentivityEditField.Value];
            app.ReceiverSitesListBox.Items{end+1} = app.ReceiverNameofSiteEditField.Value;
%             app.Latitudes = [app.Latitudes (app.ReceiverLatitudeEditField.Value)];
%             app.Longitudes = [app.Longitudes (app.ReceiverLongitudeEditField.Value)];
            
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: PlotIdealcoverageAreaButton
        function PlotIdealcoverageAreaButtonPushed(app, event)
            fq = [];
            antennas = [];
            for i = 1:length(app.transmitterFrequency)
                num = app.transmitterFrequency(i);
                str = 'e9';
                fq1 = str2double([num2str(num) str]);
                antennas = [antennas design(dipole,(fq1))];
                fq = [fq fq1];
            end
            
%             num = app.TransmitterFrequencye9HzEditField.Value;
%             str = 'e9';
%             fq = str2double([num2str(num) str]);
%             disp((fq))
            tx = txsite("Name",app.transmitters, ...
                "Latitude", app.transmitterLat, ...
                "Longitude", app.transmitterLon, ...
                "Antenna", antennas, ...
                "AntennaHeight", app.transmitterAntennaHeight, ...
                "TransmitterFrequency", fq, ...
                "TransmitterPower", app.transmitterPower);
%             tx = txsite("Name",(app.TransmitterNameofSiteEditField.Value), ...
%                 "Latitude",(app.TransmiterLatitudeEditField.Value), ...
%                 "Longitude",(app.TransmitterLongitudeEditField.Value), ...
%                 "Antenna",design(dipole,(fq)), ...
%                 "AntennaHeight",app.AntennaHeightmetersEditField.Value, ...        % Units: meters
%                 "TransmitterFrequency",fq, ... % Units: Hz
%                 "TransmitterPower",app.TransmitterPowerwattsEditField.Value);        % Units: Watts
            rxs = rxsite("Name",app.receivers, ...
                "Latitude",app.receiverLat, ...
                "Longitude",app.receiverLon, ...
                "Antenna",design(dipole,fq1), ...
                "ReceiverSensitivity",app.ReceiverSentivityEditField.Value); % Units: dBm
            app.txa = tx;

            app.rxsa = rxs;

            viewer = siteviewer;
            show(tx)
            show(rxs)
            viewer.Basemap = "openstreetmap";

            coverage(tx,app.PropagationModelDropDown.Value, ...
                "SignalStrengths",app.ReceiverSentivityEditField.Value)
            sc = [0 0.3 0];
            link(rxs,tx,app.PropagationModelDropDown.Value,"SuccessColor",sc)
        end

        % Button pushed function: PlotDirectivityPatternButton
        function PlotDirectivityPatternButtonPushed(app, event)
            yagiAnt = design(yagiUda,app.txa(1).TransmitterFrequency);

            % Tilt antenna to direct radiation in XY-plane (i.e. geographic azimuth)
            disp(app.TiltAngleEditField.Value)
            disp(app.TiltAxisDropDown.Value)

            yagiAnt.Tilt = app.TiltAngleEditField.Value;
            yagiAnt.TiltAxis = app.TiltAxisDropDown.Value;
    
            f = figure;

            % Show directivity pattern
            patternAzimuth(yagiAnt,app.txa(1).TransmitterFrequency);

            % Get the content of the figure as an image
            figImage = getframe(f);
            imageData = figImage.cdata;

            % Display the image in the UIAxes
            imshow(imageData, 'Parent', app.DirectivityPlot);
            app.txa(1).Antenna = yagiAnt;

            % Point main beam toward Boston, MA by assigning azimuth angle between
            % transmitter location and Boston receiver location
            app.txa(1).AntennaAngle = angle(app.txa(1), app.rxsa(1));

            % Define signal strengths from sensitivity to -60 dB
            sigStrengths = app.ReceiverSentivityEditField.Value:5:-60;
            sc = [0 0.3 0];

            % Update coverage map
            coverage(app.txa,"rain","SignalStrengths",sigStrengths)

            link(app.rxsa,app.txa,"rain","SuccessColor",sc)
        end

        % Value changed function: BaseMapDropDown
        function BaseMapDropDownValueChanged(app, event)
            value = app.BaseMapDropDown.Value;
            
            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, value)
        end

        % Button pushed function: PlotTargetRegionButton
        function PlotTargetRegionButtonPushed(app, event)
            app.TransmitterSitesListBox.Items = {};
            app.ReceiverSitesListBox.Items = {};
            app.transmitters = [];
            app.transmitterLat = [];
            app.transmitterLon = [];

            app.receivers = [];
            app.receiverLat = [];
            app.receiverLon = [];
            % Define the bounding box coordinates
            ne_latitude = app.SouthWestLatitudeEditField.Value;  % North-east latitude
            sw_longitude = app.NorthEastLongitudeEditField.Value;  % South-west longitude
            nw_latitude = app.NorthEastLatitudeEditField.Value;  % North-west latitude
            se_longitude = app.SouthWestLongitudeEditField.Value;  % South-east longitude

            % Plot the bounding box
            geoAxes = geoaxes(app.MapPanel);
            app.geoPlotAxes = geoAxes;
            geoplot(app.geoPlotAxes, [ne_latitude, ne_latitude, nw_latitude, nw_latitude, ne_latitude], [sw_longitude, se_longitude, se_longitude, sw_longitude, sw_longitude], 'g-.', 'LineWidth', 2);
            hold(app.geoPlotAxes, 'on')
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: UploadFileButton
        function UploadFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Select .mat file');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled the file selection
                return;
            end

            app.PathofFileUploadedEditField.Value = [filepath filename];
            % Load the .mat file
            load(fullfile(filepath, filename));
            SiteType = data.SiteType;
            disp(SiteType)
            
            SiteName = data.Name;
            transmitterSiteNames = SiteName(SiteType == 0);
            receiverSiteNames = SiteName(SiteType == 1);
            app.transmitters = [app.transmitters, transmitterSiteNames];
            app.receivers = [app.receivers, receiverSiteNames];

            SiteLatitude = data.Latitude;
            transmitterSiteLatitude = SiteLatitude(SiteType == 0);
            receiverSiteLatitude = SiteLatitude(SiteType == 1);
            app.transmitterLat = [app.transmitterLat, transmitterSiteLatitude];
            app.receiverLat = [app.receiverLat, receiverSiteLatitude];

            SiteLongitude = data.Longitude;
            transmitterSiteLongitude = SiteLongitude(SiteType == 0);
            receiverSiteLongitude = SiteLongitude(SiteType == 1);
            app.transmitterLon = [app.transmitterLon, transmitterSiteLongitude];
            app.receiverLon = [app.receiverLon, receiverSiteLongitude];

            TransmitterAntenna = data.TransmitterAntenna;
            ReceiverAntenna = data.ReceiverAntenna;
            transmitterSiteAntenna = TransmitterAntenna(SiteType == 0);
            receiverSiteAntenna = ReceiverAntenna(SiteType == 1);
            app.transmitterAntenna = [app.transmitterAntenna, transmitterSiteAntenna];
            app.receiverAntenna = [app.receiverAntenna, receiverSiteAntenna];

            TransmitterAntennaAngle = data.TransmitterAntennaAngle;
            ReceiverAntennaAngle = data.ReceiverAntennaAngle;
            transmitterSiteAntennaAngle = TransmitterAntennaAngle(SiteType == 0);
            receiverSiteAntennaAngle = ReceiverAntennaAngle(SiteType == 1);
            app.transmitterAntennaAngle = [app.transmitterAntennaAngle, transmitterSiteAntennaAngle];
            app.receiverAntennaAngle = [app.receiverAntennaAngle, receiverSiteAntennaAngle];

            TransmitterAntennaHeight = data.TransmitterAntennaHeight;
            ReceiverAntennaHeight = data.ReceiverAntennaHeight;
            transmitterSiteAntennaHeight = TransmitterAntennaHeight(SiteType == 0);
            receiverSiteAntennaHeight = ReceiverAntennaHeight(SiteType == 1);
            app.transmitterAntennaHeight = [app.transmitterAntennaHeight, transmitterSiteAntennaHeight];
            app.receiverAntennaHeight = [app.receiverAntennaHeight, receiverSiteAntennaHeight];

            TransmitterAntennaPosition = data.TransmitterAntennaPosition;
            ReceiverAntennaPosition = data.ReceiverAntennaPosition;
            transmitterSiteAntennaPosition = TransmitterAntennaPosition(SiteType == 0);
            receiverSiteAntennaPosition = ReceiverAntennaPosition(SiteType == 1);
            app.transmitterAntennaPosition = [app.transmitterAntennaPosition, transmitterSiteAntennaPosition];
            app.receiverAntennaPosition = [app.receiverAntennaPosition, receiverSiteAntennaPosition];

            TransmitterSystemLoss = data.TransmitterSystemLoss;
            ReceiverSystemLoss = data.ReceiverSystemLoss;
            transmitterSiteSystemLoss = TransmitterSystemLoss(SiteType == 0);
            receiverSiteSystemLoss = ReceiverSystemLoss(SiteType == 1);
            app.transmitterSystemLoss = [app.transmitterSystemLoss, transmitterSiteSystemLoss];
            app.receiverSystemLoss = [app.receiverSystemLoss, receiverSiteSystemLoss];

            TransmitterFrequency = data.TransmitterFrequency;
            transmitterSiteFrequency = TransmitterFrequency(SiteType == 0);
            app.transmitterFrequency = [app.transmitterFrequency, transmitterSiteFrequency];

            TransmitterPower = data.TransmitterPower;
            transmitterSitePower = TransmitterPower(SiteType == 0);
            app.transmitterPower = [app.transmitterPower, transmitterSitePower];

            ReceiverSensitivity = data.ReceiverSensitivity;
            receiverSiteSentivity = ReceiverSensitivity(SiteType == 1);
            app.receiverSenstivity = [app.receiverSenstivity, receiverSiteSentivity];
            disp(transmitterSiteNames)
            for i = 1 : length(transmitterSiteNames)
                app.TransmitterSitesListBox.Items{end+1} = transmitterSiteNames{i};
            end

            for i = 1 : length(receiverSiteNames)
                app.ReceiverSitesListBox.Items{end+1} = receiverSiteNames{i};
            end


            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)


        end

        % Button pushed function: GenerateRandomSitesButton
        function GenerateRandomSitesButtonPushed(app, event)
            % Define the bounding box coordinates
            ne_lat = app.NorthEastLatitudeEditField_3.Value;  % northeast latitude
            ne_lon = app.NorthEastLongitudeEditField_3.Value; % northeast longitude
            sw_lat = app.SouthWestLatitudeEditField_3.Value;  % southwest latitude
            sw_lon = app.SouthWestLongitudeEditField_3.Value; % southwest longitude
            
            n = app.nEditField.Value;
            m = app.mEditField.Value;

            sites = {};


            % Generate random coordinates within the bounding box
            num_points = (n*m); % specify the number of random points to generate
            ntr = str2double(app.NumberofTransmitterinaRegionDropDown.Value);

            disp(ntr)
            disp(class(ntr))
            
            if (ntr == 1)
                
                % Calculate the latitude and longitude intervals for each cell
                latInterval = (ne_lat - sw_lat) / n;
                lonInterval = (ne_lon - sw_lon) / m;

                % Create empty arrays to store the north-west, south-east, and centroid coordinates
                nwLat = zeros(n, m);
                nwLon = zeros(n, m);
                seLat = zeros(n, m);
                seLon = zeros(n, m);
                centroidLatitude = zeros(n, m);
                centroidLongitude = zeros(n, m);
                latitudes = [];
                longitudes = [];
                sites = [];

                % Generate the matrix and calculate the coordinates
                for row = 1:n
                    for col = 1:m
                        % Calculate the north-west and south-east coordinates for the current cell
                        nwLat(row, col) = ne_lat - (row - 1) * latInterval;
                        nwLon(row, col) = sw_lon + (col - 1) * lonInterval;
                        seLat(row, col) = ne_lat - row * latInterval;
                        seLon(row, col) = sw_lon + col * lonInterval;

                        % Calculate the centroid coordinate for the current cell
                        centroidLatitude(row, col) = (nwLat(row, col) + seLat(row, col)) / 2;
                        centroidLongitude(row, col) = (nwLon(row, col) + seLon(row, col)) / 2;
                        latitudes = [latitudes, ((nwLat(row, col) + seLat(row, col)) / 2)];
                        longitudes = [longitudes, ((nwLon(row, col) + seLon(row, col)) / 2)];
                        sites = [sites, strcat(string(row),string(col))];
                    end
                end

                app.centroidLat  = centroidLatitude;
                app.centroidLon = centroidLongitude;


                app.randLatitudes = latitudes;
                app.randLongitudes = longitudes;
                app.siteN = sites;


                for i = 1 : num_points
                    a = strcat(string(latitudes(i))," - ");
                    b = string(strcat(a, string(longitudes(i))));
                    sites = [sites, string(strcat(a, string(longitudes(i))))];
                end
            elseif (ntr == 2)
                disp("entered in elseif")
                numCoord = 2*num_points;
                northEast = [ne_lat, ne_lon]; % North-east coordinate [latitude, longitude]
                southWest = [sw_lat, sw_lon]; % South-west coordinate [latitude, longitude]
                latitudes = (southWest(1) + (northEast(1) - southWest(1)) * rand(numCoord, 1));
                longitudes = (southWest(2) + (northEast(2) - southWest(2)) * rand(numCoord, 1));

                set1_lat = latitudes(1:num_points);
                set1_lon = longitudes(1:num_points);

                set2_lat = latitudes((num_points)+1:end);
                set2_lon = longitudes((num_points+1):end);

                app.randLatitudes = latitudes;
                app.randLongitudes = longitudes;

                for i = 1 : num_points
                    a = strcat(string(latitudes(i))," - ");
                    b = strcat(a, string(longitudes(i)));
                    c = strcat(b, " ; ");
                    d = strcat(c, string(latitudes(num_points + i)));
                    e = strcat(d, " - ");
                    f = strcat(e, string(longitudes(num_points + i)));
                    sites = [sites, string(f)];
                end

            end

            disp(sites);

%             app.RandomSitesDropDown.Value = sites{1};
            app.RandomSitesDropDown.Items = sites;
            app.RandomSitesDropDown.ItemsData = 1:num_points;
        end

        % Button pushed function: VisualizeSiteCoverageAreaButton
        function VisualizeSiteCoverageAreaButtonPushed(app, event)
            ntr = str2double(app.NumberofTransmitterinaRegionDropDown.Value);
            n = app.nEditField.Value;
            m = app.mEditField.Value;

            num_points = n*m;
            if ntr == 1
            
                siteNum = app.RandomSitesDropDown.Value;
                lat = app.randLatitudes(siteNum);
                long = app.randLongitudes(siteNum);
                tx = txsite("Name",string(siteNum),"Latitude",lat,"Longitude",long);
                coverage(tx,'PropagationModel','freespace')
            elseif ntr == 2
                siteNum = app.RandomSitesDropDown.Value;
                tx = txsite("Name",[string(siteNum) string(siteNum + num_points)],"Latitude",[app.randLatitudes(siteNum) app.randLatitudes(siteNum + num_points)],"Longitude",[app.randLongitudes(siteNum) app.randLongitudes(siteNum + num_points)]);
                coverage(tx,'PropagationModel','freespace')
            end
        end

        % Button pushed function: HeatMapbasedonGridButton
        function HeatMapbasedonGridButtonPushed(app, event)
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            siteNum = app.RandomSitesDropDown.Value;
            transLat  = app.randLatitudes(siteNum);
            transLon = app.randLongitudes(siteNum);
            % Create a transmitter site using the random location
            transmitterSite = txsite('Name', 'Transmitter Site', 'Latitude', transLat, 'Longitude', transLon);
            % Set transmitter parameters (adjust according to your specific scenario)
            transmitterFreq = 1.9e9; % Transmitter frequency in Hz
            transmitterPow = 10; % Transmitter power in dBm

            % Calculate the distance from the transmitter to each centroid coordinate
            distance = haversine(transLat, transLon, app.centroidLat, app.centroidLon);

            % Calculate the Free Space Path Loss (FSPL) in dB
            FSPLdB = 20*log10(4*pi*distance/transmitterFreq);

            % Calculate the received power in dBm
            receivedPowerdBm = transmitterPow - FSPLdB;

            % Reshape the received power values back into the n-by-m matrix
            receivedPowerMatrix = reshape(receivedPowerdBm, n, m);

            % Create a heatmap of the received power matrix
            figure;
            imagesc(receivedPowerMatrix);

            % Customize the colormap
            colormap jet; % Choose a colormap (e.g., jet, parula, colormap name)
            colorbar;     % Display a colorbar

            % Add labels and title
%             xlabel('Column Index');
%             ylabel('Row Index');
            title('Received Power Heatmap');


            function distance = haversine(lat1, lon1, lat2, lon2)
                % Convert degrees to radians
                lat1 = deg2rad(lat1);
                lon1 = deg2rad(lon1);
                lat2 = deg2rad(lat2);
                lon2 = deg2rad(lon2);

                % Radius of the Earth in kilometers
                R = 6371;

                % Calculate differences in latitude and longitude
                dlat = lat2 - lat1;
                dlon = lon2 - lon1;

                % Haversine formula
                a = sin(dlat/2).^2 + cos(lat1) .* cos(lat2) .* sin(dlon/2).^2;
                c = 2 * atan2(sqrt(a), sqrt(1-a));

                % Calculate the distance
                distance = R * c;
            end
        end

        % Button pushed function: SaveFilesButton
        function SaveFilesButtonPushed(app, event)
            app.SavingStatusEditField.Value = "Saving Started";
            n = app.nEditField.Value;
            m = app.mEditField.Value;
            num_points = n*m;
            siteNames = 1:num_points;
            
            ntr = str2double(app.NumberofTransmitterinaRegionDropDown.Value);

            if ntr == 1

                index_table = table(app.siteN', app.randLatitudes', app.randLongitudes', 'VariableNames', {'SiteName', 'Latitude', 'Longitude'});

                for i = 1 : num_points
%                     siteNum = app.RandomSitesDropDown.Value;
                    transLat  = app.randLatitudes(i);
                    transLon = app.randLongitudes(i);
                    % Create a transmitter site using the random location
                    transmitterSite = txsite('Name', 'Transmitter Site', 'Latitude', transLat, 'Longitude', transLon);
                    % Set transmitter parameters (adjust according to your specific scenario)
                    transmitterFreq = 1.9e9; % Transmitter frequency in Hz
                    transmitterPow = 10; % Transmitter power in dBm

                    % Calculate the distance from the transmitter to each centroid coordinate
                    distance = haversine(transLat, transLon, app.centroidLat, app.centroidLon);

                    % Calculate the Free Space Path Loss (FSPL) in dB
                    FSPLdB = 20*log10(4*pi*distance/transmitterFreq);

                    % Calculate the received power in dBm
                    receivedPowerdBm = transmitterPow - FSPLdB;

                    % Reshape the received power values back into the n-by-m matrix
                    receivedPowerMatrix = reshape(receivedPowerdBm, n, m);

                    % Create a heatmap of the received power matrix
                    fig = figure('Visible', 'off');
                    imagesc(receivedPowerMatrix);
                    % Customize the colormap
                    colormap jet; % Choose a colormap (e.g., jet, parula, colormap name)
                    colorbar;     % Display a colorbar
                    title('Received Power Heatmap');

                    app.figs = [app.figs, fig];
                    figFilename = sprintf('figure_%d.png', i);
                    pod = string(app.PathofDirectoryEditField.Value);
                    pathAddress = strcat(pod,"\");
                    fullFilePath = fullfile(pathAddress, figFilename);
                    saveas(fig, fullFilePath);


                end
                
                for i = 1 : num_points
                    tx = txsite("Name",string(i),"Latitude",app.randLatitudes(i),"Longitude",app.randLongitudes(i));
                    pd = coverage(tx,'PropagationModel','freespace');
                    pd_table = pd.Data;
                    filename = strcat(string(i),".csv");
                    pod = string(app.PathofDirectoryEditField.Value);
                    pathAddress = strcat(pod,"\");
                    filepath = strcat(pathAddress, filename);
                    % Save the table to a CSV file
                    writetable(pd_table, filepath);

                end

                filename = strcat("index_table",".csv");
                filepath = strcat(pathAddress, filename);
                writetable(index_table, filepath);
                app.SavingStatusEditField.Value = "Saving Successful";
            elseif ntr == 2
                set1_lat = app.randLatitudes(1:num_points);
                set2_lat = app.randLatitudes((num_points + 1): end);
                set1_long = app.randLongitudes(1:num_points);
                set2_long = app.randLongitudes((num_points + 1): end);
                index_table = table(siteNames', set1_lat, set1_long, set2_lat, set2_long, 'VariableNames', {'SiteName', 'First Site Latitude', 'First Site Longitude', 'Second Site Latitude',  'Second Site Longitude'});
                for i = 1 : num_points
                    tx = txsite("Name",[string(i) string(i + num_points)],"Latitude",[app.randLatitudes(i) app.randLatitudes(i + num_points)],"Longitude",[app.randLongitudes(i) app.randLongitudes(i + num_points)]);
                    pd = coverage(tx);
                    pd_table = pd.Data;
                    filename = strcat(string(i),".csv");
                    pod = string(app.PathofDirectoryEditField.Value);
                    pathAddress = strcat(pod,"\");
                    filepath = strcat(pathAddress, filename);
                    % Save the table to a CSV file
                    writetable(pd_table, filepath);

                end

                filename = strcat("index_table",".csv");
                filepath = strcat(pathAddress, filename);
                writetable(index_table, filepath);
                app.SavingStatusEditField.Value = "Saving Successful";

                
            end

            function distance = haversine(lat1, lon1, lat2, lon2)
                % Convert degrees to radians
                lat1 = deg2rad(lat1);
                lon1 = deg2rad(lon1);
                lat2 = deg2rad(lat2);
                lon2 = deg2rad(lon2);

                % Radius of the Earth in kilometers
                R = 6371;

                % Calculate differences in latitude and longitude
                dlat = lat2 - lat1;
                dlon = lon2 - lon1;

                % Haversine formula
                a = sin(dlat/2).^2 + cos(lat1) .* cos(lat2) .* sin(dlon/2).^2;
                c = 2 * atan2(sqrt(a), sqrt(1-a));

                % Calculate the distance
                distance = R * c;
            end

        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value; 
            app.NumberofSampleEditField.Value = (value*app.mEditField.Value);
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.NumberofSampleEditField.Value = (value*app.nEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnvironmentalPropertiesPanel = uipanel(app.UIFigure);
            app.EnvironmentalPropertiesPanel.Title = 'Environmental Properties';
            app.TabGroup = uitabgroup(app.EnvironmentalPropertiesPanel);
            app.TargetRegionTab = uitab(app.TabGroup);
            app.TargetRegionTab.Title = 'Target Region';
            app.NorthEastLatitudeEditFieldLabel = uilabel(app.TargetRegionTab);
            app.NorthEastLatitudeEditFieldLabel.Text = 'North-East Latitude';
            app.NorthEastLatitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.NorthEastLatitudeEditField.ValueDisplayFormat = '%.3f';
            app.NorthEastLatitudeEditField.Value = 33.26;
            app.SouthWestLongitudeLabel = uilabel(app.TargetRegionTab);
            app.SouthWestLongitudeLabel.Text = 'South-West Latitude';
            app.SouthWestLatitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.SouthWestLatitudeEditField.ValueDisplayFormat = '%.3f';
            app.SouthWestLatitudeEditField.Value = 33.205;
            app.PlotTargetRegionButton = uibutton(app.TargetRegionTab, 'push');
            app.PlotTargetRegionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTargetRegionButtonPushed, true);
            app.PlotTargetRegionButton.Text = 'Plot Target Region';
            app.NorthEastLongitudeEditFieldLabel = uilabel(app.TargetRegionTab);
            app.NorthEastLongitudeEditFieldLabel.Text = 'North-East Longitude';
            app.NorthEastLongitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.NorthEastLongitudeEditField.ValueDisplayFormat = '%.3f';
            app.NorthEastLongitudeEditField.Value = -97.144;
            app.SouthWestLongitudeEditField_2Label = uilabel(app.TargetRegionTab);
            app.SouthWestLongitudeEditField_2Label.Text = 'South-West Longitude';
            app.SouthWestLongitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.SouthWestLongitudeEditField.ValueDisplayFormat = '%.3f';
            app.SouthWestLongitudeEditField.Value = -97.158;
            app.PropagationModelTab = uitab(app.TabGroup);
            app.PropagationModelTab.Title = 'Propagation Model';
            app.PropagationModelDropDownLabel = uilabel(app.PropagationModelTab);
            app.PropagationModelDropDownLabel.Text = 'Propagation Model';
            app.PropagationModelDropDown = uidropdown(app.PropagationModelTab);
            app.PropagationModelDropDown.Items = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.ItemsData = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.Value = 'rain';
            app.DirectivityPatternPanel = uipanel(app.UIFigure);
            app.DirectivityPatternPanel.Title = 'Directivity Pattern';
            app.DirectivityPlot = uiaxes(app.DirectivityPatternPanel);
            app.DirectivityPlot.XTick = [];
            app.DirectivityPlot.YTick = [];
            app.MapPanel_2 = uipanel(app.UIFigure);
            app.MapPanel_2.Title = 'Map Panel';
            app.MapPanel = uipanel(app.MapPanel_2);
            app.BaseMapDropDownLabel = uilabel(app.MapPanel_2);
            app.BaseMapDropDownLabel.Text = 'Base Map';
            app.BaseMapDropDown = uidropdown(app.MapPanel_2);
            app.BaseMapDropDown.Items = {'streets', 'darkwater', 'grayland', 'bluegreen', 'grayterrain', 'colorterrain', 'landcover', 'streets-light', 'streets-dark', 'satellite', 'topographic'};
            app.BaseMapDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseMapDropDownValueChanged, true);
            app.BaseMapDropDown.Value = 'streets';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.SwarmPropertiesTab = uitab(app.TabGroup3);
            app.SwarmPropertiesTab.Title = 'Swarm Properties';
            app.Panel = uipanel(app.SwarmPropertiesTab);
            app.TabGroup_2 = uitabgroup(app.Panel);
            app.FileUploadTab = uitab(app.TabGroup_2);
            app.FileUploadTab.Title = 'File Upload';
            app.UploadFileButton = uibutton(app.FileUploadTab, 'push');
            app.UploadFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFileButtonPushed, true);
            app.UploadFileButton.Text = 'Upload File';
            app.PathofFileUploadedEditFieldLabel = uilabel(app.FileUploadTab);
            app.PathofFileUploadedEditFieldLabel.Text = 'Path of File Uploaded';
            app.PathofFileUploadedEditField = uieditfield(app.FileUploadTab, 'text');
            app.TransmitterTab_2 = uitab(app.TabGroup_2);
            app.TransmitterTab_2.Title = 'Transmitter';
            app.Frequencye9Label = uilabel(app.TransmitterTab_2);
            app.Frequencye9Label.Text = 'Frequency (*e9 Hz)';
            app.TransmitterFrequencye9HzEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterFrequencye9HzEditField.Value = 6;
            app.AntennaHeightmetersEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.AntennaHeightmetersEditFieldLabel.Text = 'Antenna Height (meters)';
            app.AntennaHeightmetersEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.AntennaHeightmetersEditField.Value = 60;
            app.TransmitterPowerwattsEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.TransmitterPowerwattsEditFieldLabel.Text = 'Transmitter Power (watts)';
            app.TransmitterPowerwattsEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterPowerwattsEditField.Value = 15;
            app.NameofSiteEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.NameofSiteEditFieldLabel.Text = 'Name of Site';
            app.TransmitterNameofSiteEditField = uieditfield(app.TransmitterTab_2, 'text');
            app.TransmitterNameofSiteEditField.Value = 'DiscoveryPark';
            app.TransmitterAddSiteButton = uibutton(app.TransmitterTab_2, 'push');
            app.TransmitterAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitterAddSiteButtonPushed, true);
            app.TransmitterAddSiteButton.Text = 'Add Site';
            app.LongitudeEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.TransmitterLongitudeEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmitterLongitudeEditField.Value = -97.1526;
            app.LatitudeEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.TransmiterLatitudeEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmiterLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmiterLatitudeEditField.Value = 33.2538;
            app.ReceiverTab_2 = uitab(app.TabGroup_2);
            app.ReceiverTab_2.Title = 'Receiver';
            app.ReceiverSentivityEditFieldLabel = uilabel(app.ReceiverTab_2);
            app.ReceiverSentivityEditFieldLabel.Text = 'Receiver Sentivity';
            app.ReceiverSentivityEditField = uieditfield(app.ReceiverTab_2, 'numeric');
            app.ReceiverSentivityEditField.Value = -90;
            app.NameofSiteEditField_2Label = uilabel(app.ReceiverTab_2);
            app.NameofSiteEditField_2Label.Text = 'Name of Site';
            app.ReceiverNameofSiteEditField = uieditfield(app.ReceiverTab_2, 'text');
            app.ReceiverNameofSiteEditField.Value = 'UNTUnion';
            app.LatitudeEditField_2Label = uilabel(app.ReceiverTab_2);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.ReceiverLatitudeEditField = uieditfield(app.ReceiverTab_2, 'numeric');
            app.ReceiverLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLatitudeEditField.Value = 33.2107;
            app.LongitudeEditField_2Label = uilabel(app.ReceiverTab_2);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.ReceiverLongitudeEditField = uieditfield(app.ReceiverTab_2, 'numeric');
            app.ReceiverLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLongitudeEditField.Value = -97.1473;
            app.ReceiverAddSiteButton = uibutton(app.ReceiverTab_2, 'push');
            app.ReceiverAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverAddSiteButtonPushed, true);
            app.ReceiverAddSiteButton.Text = 'Add Site';
            app.PlotIdealcoverageAreaButton = uibutton(app.Panel, 'push');
            app.PlotIdealcoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIdealcoverageAreaButtonPushed, true);
            app.PlotIdealcoverageAreaButton.Text = 'Plot Ideal coverage Area';
            app.TabGroup2 = uitabgroup(app.Panel);
            app.DirectionalAntennaTab = uitab(app.TabGroup2);
            app.DirectionalAntennaTab.Title = 'Directional Antenna';
            app.TiltAngleEditFieldLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAngleEditFieldLabel.Text = 'Tilt Angle';
            app.TiltAngleEditField = uieditfield(app.DirectionalAntennaTab, 'numeric');
            app.TiltAngleEditField.Value = 90;
            app.PlotDirectivityPatternButton = uibutton(app.DirectionalAntennaTab, 'push');
            app.PlotDirectivityPatternButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDirectivityPatternButtonPushed, true);
            app.PlotDirectivityPatternButton.Text = 'Plot Directivity Pattern';
            app.TiltAxisDropDownLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAxisDropDownLabel.Text = 'Tilt Axis';
            app.TiltAxisDropDown = uidropdown(app.DirectionalAntennaTab);
            app.TiltAxisDropDown.Items = {'x', 'y'};
            app.TiltAxisDropDown.Value = 'x';
            app.ReceiverSitesListBoxLabel = uilabel(app.Panel);
            app.ReceiverSitesListBoxLabel.Text = {'Receiver'; 'Sites'};
            app.ReceiverSitesListBox = uilistbox(app.Panel);
            app.ReceiverSitesListBox.Items = {};
            app.ReceiverSitesListBox.Value = {};
            app.TransmitterSitesListBoxLabel = uilabel(app.Panel);
            app.TransmitterSitesListBoxLabel.Text = {'Transmitter'; 'Sites'};
            app.TransmitterSitesListBox = uilistbox(app.Panel);
            app.TransmitterSitesListBox.Items = {};
            app.TransmitterSitesListBox.Value = {};
            app.SignalMapGenerationTab_2 = uitab(app.TabGroup3);
            app.SignalMapGenerationTab_2.Title = 'Signal Map Generation';
            app.NorthEastLatitudeEditField_3Label = uilabel(app.SignalMapGenerationTab_2);
            app.NorthEastLatitudeEditField_3Label.Text = 'North-East Latitude';
            app.NorthEastLatitudeEditField_3 = uieditfield(app.SignalMapGenerationTab_2, 'numeric');
            app.NorthEastLatitudeEditField_3.ValueDisplayFormat = '%.3f';
            app.NorthEastLatitudeEditField_3.Value = 33.29;
            app.SouthWestLatitudeEditField_3Label = uilabel(app.SignalMapGenerationTab_2);
            app.SouthWestLatitudeEditField_3Label.Text = 'South-West Latitude';
            app.SouthWestLatitudeEditField_3 = uieditfield(app.SignalMapGenerationTab_2, 'numeric');
            app.SouthWestLatitudeEditField_3.ValueDisplayFormat = '%.3f';
            app.SouthWestLatitudeEditField_3.Value = 32.8;
            app.NorthEastLongitudeEditField_3Label = uilabel(app.SignalMapGenerationTab_2);
            app.NorthEastLongitudeEditField_3Label.Text = 'North-East Longitude';
            app.NorthEastLongitudeEditField_3 = uieditfield(app.SignalMapGenerationTab_2, 'numeric');
            app.NorthEastLongitudeEditField_3.ValueDisplayFormat = '%.3f';
            app.NorthEastLongitudeEditField_3.Value = -96.8;
            app.SouthWestLongitudeEditField_3Label = uilabel(app.SignalMapGenerationTab_2);
            app.SouthWestLongitudeEditField_3Label.Text = 'South-West Longitude';
            app.SouthWestLongitudeEditField_3 = uieditfield(app.SignalMapGenerationTab_2, 'numeric');
            app.SouthWestLongitudeEditField_3.ValueDisplayFormat = '%.3f';
            app.SouthWestLongitudeEditField_3.Value = -97.2;
            app.DistributionDropDown_2Label = uilabel(app.SignalMapGenerationTab_2);
            app.DistributionDropDown_2Label.Text = 'Distribution';
            app.DistributionDropDown_2 = uidropdown(app.SignalMapGenerationTab_2);
            app.DistributionDropDown_2.Items = {'Linear'};
            app.DistributionDropDown_2.Value = 'Linear';
            app.NumberofTransmitterinaRegionDropDownLabel = uilabel(app.SignalMapGenerationTab_2);
            app.NumberofTransmitterinaRegionDropDownLabel.Text = 'Number of Transmitter in a Region';
            app.NumberofTransmitterinaRegionDropDown = uidropdown(app.SignalMapGenerationTab_2);
            app.NumberofTransmitterinaRegionDropDown.Items = {'1', '2'};
            app.NumberofTransmitterinaRegionDropDown.Value = '1';
            app.RandomSitesDropDownLabel = uilabel(app.SignalMapGenerationTab_2);
            app.RandomSitesDropDownLabel.Text = 'Random Sites';
            app.RandomSitesDropDown = uidropdown(app.SignalMapGenerationTab_2);
            app.GenerateRandomSitesButton = uibutton(app.SignalMapGenerationTab_2, 'push');
            app.GenerateRandomSitesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomSitesButtonPushed, true);
            app.GenerateRandomSitesButton.Text = 'Generate Random Sites';
            app.VisualizeSiteCoverageAreaButton = uibutton(app.SignalMapGenerationTab_2, 'push');
            app.VisualizeSiteCoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeSiteCoverageAreaButtonPushed, true);
            app.VisualizeSiteCoverageAreaButton.Text = 'Visualize Site Coverage Area';
            app.PathofDirectoryEditFieldLabel = uilabel(app.SignalMapGenerationTab_2);
            app.PathofDirectoryEditFieldLabel.Text = 'Path of Directory ';
            app.PathofDirectoryEditField = uieditfield(app.SignalMapGenerationTab_2, 'text');
            app.PathofDirectoryEditField.Value = 'C:\Users\soura\OneDrive\Desktop\test';
            app.SaveFilesButton = uibutton(app.SignalMapGenerationTab_2, 'push');
            app.SaveFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFilesButtonPushed, true);
            app.SaveFilesButton.Text = 'Save Files';
            app.SavingStatusEditFieldLabel = uilabel(app.SignalMapGenerationTab_2);
            app.SavingStatusEditFieldLabel.Enable = 'off';
            app.SavingStatusEditFieldLabel.Text = 'Saving Status';
            app.SavingStatusEditField = uieditfield(app.SignalMapGenerationTab_2, 'text');
            app.SavingStatusEditField.Editable = 'off';
            app.SavingStatusEditField.Enable = 'off';
            app.SavingStatusEditField.Value = 'Not Saving';
            app.NumberofSampleEditFieldLabel = uilabel(app.SignalMapGenerationTab_2);
            app.NumberofSampleEditFieldLabel.Enable = 'off';
            app.NumberofSampleEditFieldLabel.Text = 'Number of Sample ';
            app.NumberofSampleEditField = uieditfield(app.SignalMapGenerationTab_2, 'numeric');
            app.NumberofSampleEditField.Limits = [1 Inf];
            app.NumberofSampleEditField.Editable = 'off';
            app.NumberofSampleEditField.Enable = 'off';
            app.NumberofSampleEditField.Value = 1;
            app.nEditFieldLabel = uilabel(app.SignalMapGenerationTab_2);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.SignalMapGenerationTab_2, 'numeric');
            app.nEditField.Limits = [1 Inf];
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 1;
            app.mEditFieldLabel = uilabel(app.SignalMapGenerationTab_2);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.SignalMapGenerationTab_2, 'numeric');
            app.mEditField.Limits = [1 Inf];
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 1;
            app.HeatMapbasedonGridButton = uibutton(app.SignalMapGenerationTab_2, 'push');
            app.HeatMapbasedonGridButton.ButtonPushedFcn = createCallbackFcn(app, @HeatMapbasedonGridButtonPushed, true);
            app.HeatMapbasedonGridButton.Text = 'Heat Map based on Grid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeAntennaCoverage_test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end